classdef Lab12 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Hyperlink                      matlab.ui.control.Hyperlink
        PulseCountingChannelDropDown   matlab.ui.control.DropDown
        PulseCountingChannelDropDownLabel  matlab.ui.control.Label
        CurrentmAEditField             matlab.ui.control.NumericEditField
        CurrentmAEditFieldLabel        matlab.ui.control.Label
        LEDResistorEditField           matlab.ui.control.NumericEditField
        LEDResistorLabel               matlab.ui.control.Label
        AnalogOutputChannelDropDown    matlab.ui.control.DropDown
        AnalogOutputChannelDropDownLabel  matlab.ui.control.Label
        AnalogInputChannelDropDown     matlab.ui.control.DropDown
        AnalogInputChannelDropDownLabel  matlab.ui.control.Label
        LEDWavelengthnmEditField       matlab.ui.control.NumericEditField
        LEDWavelengthnmEditFieldLabel  matlab.ui.control.Label
        TimeBetweenSamplessEditField   matlab.ui.control.NumericEditField
        Label                          matlab.ui.control.Label
        LEDCurrentStepSizemAEditField  matlab.ui.control.NumericEditField
        LEDCurrentStepSizemAEditFieldLabel  matlab.ui.control.Label
        MaximumLEDCurrentmAEditField   matlab.ui.control.NumericEditField
        MaximumLEDCurrentmAEditFieldLabel  matlab.ui.control.Label
        StopButton                     matlab.ui.control.Button
        StartButton                    matlab.ui.control.Button
        UIAxes_Pulse_Frequency         matlab.ui.control.UIAxes
        UIAxes_Light_Intensity         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)

        AnalogIn_0 % Voltage across LED resistor to calculate LED current
        AnalogOut_0 % Ramp current created by DAQ board
        PF_0

        Channel_0
        Channel_1
        Channel_3
        Counter

        d % DAQ session
        dev % DAQ device

        VariableNames = {'LED Current','Pulse Frequency','Light Intensity'};
        VariableUnits = {'mA','Hz','W/m^2'};
        AllDataTimeTable
        frequency_data
        intensity_data
        LED_input
        Ramp_samples
        LED_current % Array of current values from LED
        LED_output % Array of voltage values from ramp
         

        % Edit field variables
        LED_Wavelength
        Time_Between
        LED_Stepsize
        Max_Current
        Instant_Current
        

        % Constants
        V_drop = 2;
        I_max = 3; % 3 mA
        LED_resistor = 100; % 100 Ohms
        Dectector_response = 2.3; % kHz/uW/cm^2
        f_dark = 150;
        

    end
    
    methods (Access = private)
        
        function GenRampPattern(app)
            app.LED_input = 0; % Current = 0 mA
            app.Ramp_samples = app.Max_Current / 0.1; % Calculate number of steps per ramp
            V_max = app.I_max * app.LED_resistor;
            while (i < app.Ramp_samples)
                write(app.d,app.LED_output);
            end
        end

        
        function calc_LED_current = LED_I(~,app)
            calc_LED_current = app.LED_output / app.LED_resistor;
        end
        
        function intensity = calc_intensity(~,app)
            intensity = (app.frequency_data - app.f_dark) / app.Dector_response; 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            devices    = daqlist("ni");
            app.dev    = devices(1,"DeviceID");
            app.d      = daq('ni');
    
            app.AnalogIn_0 = app.AnalogInputChannelDropDown;
            app.AnalogOut_0 = app.AnalogOutputChannelDropDown;
            app.PF_0 = app.PulseCountingChannelDropDown;


        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % Make buttons/edit fields idiot proof
            app.StartButton.Enable = "off"; 
            app.LEDResistorEditField.Enable = "off";
            app.LEDWavelengthnmEditField.Enable = "off";
            app.TimeBetweenSamplessEditField.Enable = "off";
            app.LEDCurrentStepSizemAEditField.Enable = "off";
            app.MaximumLEDCurrentmAEditField.Enable = "off";
            app.AnalogInputChannelDropDown.Enable = "off";
            app.AnalogOutputChannelDropDown.Enable = "off";
            app.PulseCountingChannelDropDown.Enable = "off";

            app.Channel_0 = addinput(app.d,app.dev.DeviceID,app.AnalogIn_0,'Voltage');
            app.Channel_1 = addoutput(app.d,app.dev.DeviceID,app.AnalogOut_0,'Voltage');
            %app.Channel_3 = addinput(app.d,app.dev.DeviceID,app.PF_0,'Frequency');
            
            app.Channel_0.TerminalConfig='Differential'; 

            app.i = 0;
            app.scanData = read(app.d,app.LED_output',"OutputFormat","Matrix");
            pause(0.1);
            write(app.d,app.dev.DeviceID)
            
            write(app.d,0); % Send out 0V at the end of the ramp

            plot(app.UIAxes_Light_Intensity,app.LED_current,app.intensity_data)
            plot(app.UIAxes_Frequency,app.LED_current,app.frequency_data)
    

            % Create a timetable that can be appended to
            app.AllDataTimeTable = timetable(NaT(0,1),[],[],[]);
            app.AllDataTimeTable.Properties.VariableNames = {'LED Current','Pulse Frequency','Light Intensity'};
            app.AllDataTimeTable.Properties.VariableUnits = {'mA','Hz','W/m^2'};
            
            
            % Clear plots for consecutive measurments
            cla(app.UIAxes_Pulse_Frequency); 
            cla(app.UIAxes_Light_Intensity);

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % Make buttons/edit fields idiot proof
            app.StopButton.Enable = "off";
            app.PulseCountingChannelDropDown.Enable = "on";
            app.CurrentmAEditField.Enable = "on";
            app.LEDResistorEditField.Enable = "on";
            app.AnalogOutputChannelDropDown.Enable = "on";
            app.AnalogInputChannelDropDown.Enable = "on";
            app.LEDWavelengthnmEditField.Enable = "on";
            app.TimeBetweenSamplessEditField.Enable = "on";
            app.LEDCurrentStepSizemAEditField.Enable = "on";
            app.MaximumLEDCurrentmAEditField.Enable = "on";
            
            writetimetable(app.AllDataTimeTable,"Barber_Lab12data.csv");
            %stop(app.d) 
            
        end

        % Value changed function: LEDWavelengthnmEditField
        function LEDWavelengthnmEditFieldValueChanged(app, event)
            app.LED_Wavelength = app.LEDWavelengthnmEditField.Value;
            
        end

        % Value changed function: TimeBetweenSamplessEditField
        function TimeBetweenSamplessEditFieldValueChanged(app, event)
            app.Time_Between= app.TimeBetweenSamplessEditField.Value;
            
        end

        % Value changed function: LEDCurrentStepSizemAEditField
        function LEDCurrentStepSizemAEditFieldValueChanged(app, event)
            app.LED_Stepsize = app.LEDCurrentStepSizemAEditField.Value;
            
        end

        % Value changed function: MaximumLEDCurrentmAEditField
        function MaximumLEDCurrentmAEditFieldValueChanged(app, event)
            if app.MaximumLEDCurrentmAEditField.Value >= app.I_max
                app.Max_Current = app.I_max;
            else
                app.Max_Current = app.MaximumLEDCurrentmAEditField.Value;
            end     
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_Light_Intensity = uiaxes(app.UIFigure);
            title(app.UIAxes_Light_Intensity, 'Light Intensity VS LED Current')
            xlabel(app.UIAxes_Light_Intensity, 'LED Drive Current (mA)')
            ylabel(app.UIAxes_Light_Intensity, 'Light Intensity (W/m^2)')
            zlabel(app.UIAxes_Light_Intensity, 'Z')
            app.UIAxes_Pulse_Frequency = uiaxes(app.UIFigure);
            title(app.UIAxes_Pulse_Frequency, 'Pulse Frequency VS LED Current')
            xlabel(app.UIAxes_Pulse_Frequency, 'LED Drive Current (mA)')
            ylabel(app.UIAxes_Pulse_Frequency, 'Pulse Frequency (Hz)')
            zlabel(app.UIAxes_Pulse_Frequency, 'Z')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.MaximumLEDCurrentmAEditFieldLabel = uilabel(app.UIFigure);
            app.MaximumLEDCurrentmAEditFieldLabel.Text = 'Maximum LED Current (mA)';
            app.MaximumLEDCurrentmAEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaximumLEDCurrentmAEditField.Limits = [0 3];
            app.MaximumLEDCurrentmAEditField.ValueChangedFcn = createCallbackFcn(app, @MaximumLEDCurrentmAEditFieldValueChanged, true);
            app.MaximumLEDCurrentmAEditField.Value = 3;
            app.LEDCurrentStepSizemAEditFieldLabel = uilabel(app.UIFigure);
            app.LEDCurrentStepSizemAEditFieldLabel.Text = 'LED Current Step Size (mA)';
            app.LEDCurrentStepSizemAEditField = uieditfield(app.UIFigure, 'numeric');
            app.LEDCurrentStepSizemAEditField.Limits = [0 1];
            app.LEDCurrentStepSizemAEditField.ValueChangedFcn = createCallbackFcn(app, @LEDCurrentStepSizemAEditFieldValueChanged, true);
            app.LEDCurrentStepSizemAEditField.Value = 0.1;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Time Between Samples (s)';
            app.TimeBetweenSamplessEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeBetweenSamplessEditField.Limits = [0 Inf];
            app.TimeBetweenSamplessEditField.ValueChangedFcn = createCallbackFcn(app, @TimeBetweenSamplessEditFieldValueChanged, true);
            app.TimeBetweenSamplessEditField.Value = 1;
            app.LEDWavelengthnmEditFieldLabel = uilabel(app.UIFigure);
            app.LEDWavelengthnmEditFieldLabel.Text = 'LED Wavelength (nm)';
            app.LEDWavelengthnmEditField = uieditfield(app.UIFigure, 'numeric');
            app.LEDWavelengthnmEditField.Limits = [300 800];
            app.LEDWavelengthnmEditField.ValueChangedFcn = createCallbackFcn(app, @LEDWavelengthnmEditFieldValueChanged, true);
            app.LEDWavelengthnmEditField.Value = 524;
            app.AnalogInputChannelDropDownLabel = uilabel(app.UIFigure);
            app.AnalogInputChannelDropDownLabel.Text = 'Analog Input Channel';
            app.AnalogInputChannelDropDown = uidropdown(app.UIFigure);
            app.AnalogInputChannelDropDown.Items = {'AI0', 'AI1', 'AI2', 'AI3', 'AI4', 'AI5', 'AI6', 'AI7'};
            app.AnalogInputChannelDropDown.Tooltip = {'Select the input channel used for measuing the LED current (from the LED resistor).'};
            app.AnalogInputChannelDropDown.Value = 'AI0';
            app.AnalogOutputChannelDropDownLabel = uilabel(app.UIFigure);
            app.AnalogOutputChannelDropDownLabel.Text = 'Analog Output Channel';
            app.AnalogOutputChannelDropDown = uidropdown(app.UIFigure);
            app.AnalogOutputChannelDropDown.Items = {'AO0', 'AO1'};
            app.AnalogOutputChannelDropDown.Tooltip = {'Select the output channel for ramp voltage.'};
            app.AnalogOutputChannelDropDown.Value = 'AO0';
            app.LEDResistorLabel = uilabel(app.UIFigure);
            app.LEDResistorLabel.Text = 'LED Resistor (Ω)';
            app.LEDResistorEditField = uieditfield(app.UIFigure, 'numeric');
            app.LEDResistorEditField.Limits = [0 1000];
            app.LEDResistorEditField.Tooltip = {'LED resistor used to calculate LED current.'};
            app.LEDResistorEditField.Value = 100;
            app.CurrentmAEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentmAEditFieldLabel.Text = 'Current (mA)';
            app.CurrentmAEditField = uieditfield(app.UIFigure, 'numeric');
            app.CurrentmAEditField.Tooltip = {'Displays the instantaneous current supplied by the ramp.'};
            app.PulseCountingChannelDropDownLabel = uilabel(app.UIFigure);
            app.PulseCountingChannelDropDownLabel.Text = 'Pulse Counting Channel';
            app.PulseCountingChannelDropDown = uidropdown(app.UIFigure);
            app.PulseCountingChannelDropDown.Items = {'PF I/O 0', 'PF I/O 1', 'PF I/O 2', 'PF I/O 3', 'PF I/O 4', 'PF I/O 5', 'PF I/O 6', 'PF I/O 7'};
            app.PulseCountingChannelDropDown.Value = 'PF I/O 0';
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.URL = 'http://localhost:8889/lab/tree/SMPS%20Data/Lab12README.ipynb';
            app.Hyperlink.Text = 'Documentation';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lab12

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end