classdef Lab9 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SampleRate                    matlab.ui.control.NumericEditField
        SampleRateHzLabel             matlab.ui.control.Label
        VregChannelDropDown           matlab.ui.control.DropDown
        VoltageRegulatorChannelLabel  matlab.ui.control.Label
        PressureChannelDropDown       matlab.ui.control.DropDown
        PressureChannelLabel          matlab.ui.control.Label
        RHChannelDropDown             matlab.ui.control.DropDown
        RHChannelLabel                matlab.ui.control.Label
        TemperatureChannelDropDown    matlab.ui.control.DropDown
        TemperatureChannelLabel       matlab.ui.control.Label
        ClearButton                   matlab.ui.control.Button
        StopButton                    matlab.ui.control.Button
        StartButton                   matlab.ui.control.Button
        UIAxes_DewPoint               matlab.ui.control.UIAxes
        UIAxes_RelativeHumidity       matlab.ui.control.UIAxes
        UIAxes_Pressure               matlab.ui.control.UIAxes
        UIAxes_Temperature            matlab.ui.control.UIAxes
        UIAxes_VoltageRegulator       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        AnalogIn0  % Analog input (Temp sensor)
        AnalogIn1  % Analog input (RH sensor)
        AnalogIn2  % Analog input (Pres sensor)
        AnalogIn3  % Analog input (Voltreg)


        d          % Daq session
        dev      % Daq device

        inTemp_v 
        inRH_v
        inPres_v
        reg_v
        
        chan_t     % Variable for temperature channel
        chan_RH    % Variable for RH channel
        chan_p     % Variable for pressure channel
        chan_v     % Variable for voltage regulator channel
        
        chan_t_default     % Default variable for temperature channel
        chan_RH_default    % Default variable for RH channel
        chan_p_default     % Default variable for pressure channel
        chan_v_default     % Default variable for voltage regulator channel
        chan_flag

        data       % Matrix of voltage channels

        VariableNames = {'Temperature Voltage','RH Voltage','Pressure Voltage','Regulator Voltage','Temperature','Pressure','RH','Dew Point'};
        VariableUnits = {'V','V','V','V','C','kPa','%','C'};
        timestamps % Read in with data as a matrix
        AllDataTimeTable
        NewDataTimeTable
         

    end

     methods (Access = private)
        
        function plotData(app, scan, ~)
            [app.data, app.timestamps, ~] = read(scan, scan.ScansAvailableFcnCount, "OutputFormat", "Matrix");

            % Get voltages to calculate t,p,z
            app.inTemp_v = app.data(:,1);
            app.inRH_v = app.data(:,2);
            app.inPres_v = app.data(:,3);
            app.reg_v = app.data(:,4);

            % Functions to calculate and plot values
            T = calctemp(app,app.inTemp_v);
            P = calcpres(app,app.inPres_v,app.reg_v);
            RH = calcRH(app,app.inRH_v);
            DP = calcDP(app,app.inTemp_v,RH);
    
            % Make graphs of all measurements
            plot(app.UIAxes_Temperature, app.timestamps, T, "b");
            plot(app.UIAxes_Pressure, app.timestamps, P, "g");
            plot(app.UIAxes_VoltageRegulator, app.timestamps, app.reg_v, "c");
            plot(app.UIAxes_RelativeHumidity, app.timestamps, RH,"m");
            plot(app.UIAxes_DewPoint, app.timestamps, DP, "y");

            hold(app.UIAxes_Temperature, 'on');
            hold(app.UIAxes_Pressure, 'on');
            hold(app.UIAxes_VoltageRegulator, 'on');
            hold(app.UIAxes_RelativeHumidity, 'on');
            hold(app.UIAxes_DewPoint, 'on');

            % Make timetable of measurements
            app.NewDataTimeTable = timetable(datetime('now'),mean(app.inTemp_v),mean(app.inRH_v),mean(app.inPres_v),mean(app.reg_v), ...
                                                            mean(T),mean(RH),mean(P),mean(DP));
            app.NewDataTimeTable.Properties.VariableNames=app.AllDataTimeTable.Properties.VariableNames;
            app.AllDataTimeTable=vertcat(app.AllDataTimeTable,app.NewDataTimeTable); 

        end

        function T = calctemp(~,Vtemp)    % Create function that calculates temperature
            T = Vtemp/0.01; 
        end
        
        function P = calcpres(~,Vpres, Vreg)  % Create function that calculates pressure
            P = (1/0.009) * ((Vpres./Vreg) + 0.095); 
        end

        function RH = calcRH(~,Vout)    % Create function that calculates relative humidity
            RH = (Vout - 0.825)/0.03;
        end

        function DP = calcDP(~,Vtemp,RH)    % Create function that calculates dew point
            DP = Vtemp - ((100-RH)/5);
        end

        % Set default drop down values and prevent double use
        function ValidChannel = channel_Logic(app,channel_name,channel_default,channel_ddv)
            app.chan_flag = 0;            
            if string(channel_ddv) == string(app.chan_t) && channel_name ~= "app.chan_t"
                ValidChannel = channel_default;
                app.channelFlag = 1;
            elseif string(channel_ddv) == string(app.chan_v) && channel_name ~= "app.chan_v"
                ValidChannel = channel_default;
                app.channelFlag = 1;
            elseif string(channel_ddv) == string(app.chan_p) && channel_name ~= "app.chan_p"
                ValidChannel = channel_default;
                app.channelFlag = 1;
            elseif string(channel_ddv) == string(app.chan_RH) && channel_name ~= "app.chan_RH"
                ValidChannel = channel_default;
                app.channelFlag = 1;
            else 
                ValidChannel = channel_ddv;
            end
            if app.chan_flag == 1
                if app.chan_t == channel_default
                    app.TemperatureChannelDropDown.Value = app.chan_t_default;
                elseif app.chan_p == channel_default
                    app.PressureChannelDropDown.Value = app.chan_p_default;
                elseif app.chan_v == channel_default
                    app.VregChannelDropDown.Value = app.chan_v_default;
                elseif app.ch_RH == channel_default
                    app.RHChannelDropDown.Value = app.chan_RH_default;
                end
            end
        end
     end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.StopButton.Enable = "off";
            devices    = daqlist("ni");
            app.dev    = devices(1,"DeviceID");
            app.d      = daq('ni');

            app.chan_t= app.TemperatureChannelDropDown.Value;
            app.chan_p = app.PressureChannelDropDown.Value;
            app.chan_v = app.VregChannelDropDown.Value;

            % Set channels to default values at the beginning of the App
            app.chan_t_default = app.chan_t;
            app.chan_RH_default = app.chan_RH;
            app.chan_p_default = app.chan_p;
            app.chan_v_default = app.chan_v;

            app.d.Rate = 1; 
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.StartButton.Enable = "off"; % Prevent double clicks
            app.StopButton.Enable = "on";
            app.SampleRate.Enable = "off";  % Prevent chanign ai channels
            app.TemperatureChannelDropDown.Enable = "off";
            app.RHChannelDropDown.Enable = "off";
            app.PressureChannelDropDown.Enable = "off";
            app.VregChannelDropDown.Enable = "off";
            
            app.AnalogIn0 = addinput(app.d,app.dev.DeviceID,app.chan_t,"Voltage"); % Temp voltage
            app.AnalogIn1 = addinput(app.d,app.dev.DeviceID,app.chan_RH,"Voltage"); % RH voltage
            app.AnalogIn2 = addinput(app.d,app.dev.DeviceID,app.chan_p,"Voltage"); % Pres voltage
            app.AnalogIn3 = addinput(app.d,app.dev.DeviceID,app.chan_v,"Voltage"); % Vreg voltage

            app.AnalogIn0.TerminalConfig = 'Differential'; % Differential mode on Daq board 
            app.AnalogIn1.TerminalConfig = 'Differential'; % Differential mode on Daq board
            app.AnalogIn2.TerminalConfig = 'Differential'; % Differential mode on Daq board
            app.AnalogIn3.TerminalConfig = 'Differential'; % Differential mode on Daq board


            % Create a timetable that can be appended to
            app.AllDataTimeTable = timetable(NaT(0,1),[],[],[],[],[],[],[],[]);
            app.AllDataTimeTable.Properties.VariableNames = {'V_temp','V_RH','V_pressure','V_reg','T','RH','P','DP'};
            app.AllDataTimeTable.Properties.VariableUnits = {'V','V','V','V','degC','%','kPa','degC'};
            
            
            %Clear plots for consecutive measurements
            cla(app.UIAxes_Temperature); 
            cla(app.UIAxes_RelativeHumidity);
            cla(app.UIAxes_Pressure);
            cla(app.UIAxes_VoltageRegulator);
            cla(app.UIAxes_DewPoint);

            app.d.ScansAvailableFcnCount = app.d.Rate*1;  % sample rate
            app.d.ScansAvailableFcn = @(scan, evt) plotData(app, scan);
            start(app.d, "Continuous");
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.StopButton.Enable = "off";
            app.StartButton.Enable = "on";
            app.VregChannelDropDown.Enable = "on";
            app.PressureChannelDropDown.Enable = "on";
            app.RHChannelDropDown.Enable = "on";
            app.TemperatureChannelDropDown.Enable = "on";
            app.SampleRate.Enable = "on";

            writetimetable(app.AllDataTimeTable,"Barber_Lab09data.csv");

            stop(app.d);
            removechannel(app.d,1:length(app.d.Channels));
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.ClearButton.Enable = "off"; % Prevent double clicks
            
            cla(app.UIAxes_Temperature,'reset'); % Clear all graphs
            cla(app.UIAxes_RelativeHumidity,'reset');
            cla(app.UIAxes_Pressure,'reset');
            cla(app.UIAxes_VoltageRegulator,'reset');
            cla(app.UIAxes_DewPoint,'reset');

        end

        % Value changed function: TemperatureChannelDropDown
        function TemperatureChannelDropDownValueChanged(app, event)
            app.chan_t = app.TemperatureChannelDropDown.Value;
            app.TemperatureChannelDropDown.Value = channel_Logic(app,"chan_t",app.chan_t,app.chan_t_default,app.TemperatureChannelDropDown.Value);
           
        end

        % Value changed function: RHChannelDropDown
        function RHChannelDropDownValueChanged(app, event)
            app.chan_RH = app.RHChannelDropDown.Value; 
            app.RHChannelDropDown.Value = channel_Logic(app,"chan_RH",app.chan_RH,app.chan_RH_default,app.RHChannelDropDown.Value);
            
        end

        % Value changed function: PressureChannelDropDown
        function PressureChannelDropDownValueChanged(app, event)
            app.chan_p = app.PressureChannelDropDown.Value;
            app.PressureChannelDropDown.Value = channel_Logic(app,"chan_p",app.chan_p,app.chan_p_default,app.PressureChannelDropDown.Value);
           
        end

        % Value changed function: VregChannelDropDown
        function VregChannelDropDownValueChanged(app, event)
            app.chan_v = app.VregChannelDropDown.Value;
            app.VregChannelDropDown.Value = channel_Logic(app,"chan_v",app.chan_v,app.chan_v_default,app.VregChannelDropDown.Value);

        end

        % Value changed function: SampleRate
        function SampleRateValueChanged(app, event)
            app.d.Rate = app.SampleRate.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_VoltageRegulator = uiaxes(app.UIFigure);
            title(app.UIAxes_VoltageRegulator, 'Voltage Regulator')
            xlabel(app.UIAxes_VoltageRegulator, 'Time (s)')
            ylabel(app.UIAxes_VoltageRegulator, 'Voltage')
            zlabel(app.UIAxes_VoltageRegulator, 'Z')
            app.UIAxes_Temperature = uiaxes(app.UIFigure);
            title(app.UIAxes_Temperature, 'Temperature')
            xlabel(app.UIAxes_Temperature, 'Time (s)')
            ylabel(app.UIAxes_Temperature, 'Temperature (°C)')
            zlabel(app.UIAxes_Temperature, 'Z')
            app.UIAxes_Pressure = uiaxes(app.UIFigure);
            title(app.UIAxes_Pressure, 'Pressure')
            xlabel(app.UIAxes_Pressure, 'Time (s)')
            ylabel(app.UIAxes_Pressure, 'Pressure (kPa)')
            zlabel(app.UIAxes_Pressure, 'Z')
            app.UIAxes_RelativeHumidity = uiaxes(app.UIFigure);
            title(app.UIAxes_RelativeHumidity, 'RH')
            xlabel(app.UIAxes_RelativeHumidity, 'Time (s)')
            ylabel(app.UIAxes_RelativeHumidity, '%')
            zlabel(app.UIAxes_RelativeHumidity, 'Z')
            app.UIAxes_DewPoint = uiaxes(app.UIFigure);
            title(app.UIAxes_DewPoint, 'Dew Point')
            xlabel(app.UIAxes_DewPoint, 'Time (s)')
            ylabel(app.UIAxes_DewPoint, 'Temperature (°C)')
            zlabel(app.UIAxes_DewPoint, 'Z')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.TemperatureChannelLabel = uilabel(app.UIFigure);
            app.TemperatureChannelLabel.Text = 'Temperature Channel';
            app.TemperatureChannelDropDown = uidropdown(app.UIFigure);
            app.TemperatureChannelDropDown.Items = {'ai0', 'ai1', 'ai2', 'ai3', 'ai4', 'ai5', 'ai6', 'ai7'};
            app.TemperatureChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @TemperatureChannelDropDownValueChanged, true);
            app.TemperatureChannelDropDown.Value = 'ai0';
            app.RHChannelLabel = uilabel(app.UIFigure);
            app.RHChannelLabel.Text = 'RH Channel';
            app.RHChannelDropDown = uidropdown(app.UIFigure);
            app.RHChannelDropDown.Items = {'ai0', 'ai1', 'ai2', 'ai3', 'ai4', 'ai5', 'ai6', 'ai7'};
            app.RHChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @RHChannelDropDownValueChanged, true);
            app.RHChannelDropDown.Value = 'ai1';
            app.PressureChannelLabel = uilabel(app.UIFigure);
            app.PressureChannelLabel.Text = 'Pressure Channel';
            app.PressureChannelDropDown = uidropdown(app.UIFigure);
            app.PressureChannelDropDown.Items = {'ai0', 'ai1', 'ai2', 'ai3', 'ai4', 'ai5', 'ai6', 'ai7'};
            app.PressureChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @PressureChannelDropDownValueChanged, true);
            app.PressureChannelDropDown.Value = 'ai2';
            app.VoltageRegulatorChannelLabel = uilabel(app.UIFigure);
            app.VoltageRegulatorChannelLabel.Text = 'Voltage Regulator Channel';
            app.VregChannelDropDown = uidropdown(app.UIFigure);
            app.VregChannelDropDown.Items = {'ai0', 'ai1', 'ai2', 'ai3', 'ai4', 'ai5', 'ai6', 'ai7'};
            app.VregChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @VregChannelDropDownValueChanged, true);
            app.VregChannelDropDown.Value = 'ai3';
            app.SampleRateHzLabel = uilabel(app.UIFigure);
            app.SampleRateHzLabel.Text = 'Sample Rate (Hz)';
            app.SampleRate = uieditfield(app.UIFigure, 'numeric');
            app.SampleRate.ValueChangedFcn = createCallbackFcn(app, @SampleRateValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lab9

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end