classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SampleRate                    matlab.ui.control.NumericEditField
        SampleRateHzEditFieldLabel    matlab.ui.control.Label
        ClearButton                   matlab.ui.control.Button
        VregChannel                   matlab.ui.control.DropDown
        VregChannelDropDownLabel      matlab.ui.control.Label
        PressureChannel               matlab.ui.control.DropDown
        PressureChannelDropDownLabel  matlab.ui.control.Label
        TemperatureChannel            matlab.ui.control.DropDown
        TemperatureChannelDropDownLabel  matlab.ui.control.Label
        StopButton                    matlab.ui.control.Button
        StartButton                   matlab.ui.control.Button
        UIAxes_VoltageRegulator       matlab.ui.control.UIAxes
        UIAxes_Altitude               matlab.ui.control.UIAxes
        UIAxes_Pressure               matlab.ui.control.UIAxes
        UIAxes_Temperature            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        AnalogIn1  % Analog input (Temp sensor)
        AnalogIn2  % Analog input (Pres sensor)
        AnalogIn3  % Analog input (Voltreg)
        d          % Daq session
        dev        % Daq device
        data       % Matrix of voltage channels
        Vtemp
        Vpres
        Vreg
        timestamps % Read in with data as a matrix
        AllDataTimeTable
        NewDateTimeTable
    end
    
    methods (Access = private)
        
        function plotData(app, scan, ~)
            [app.data, app.timestamps, ~] = read(scan, scan.ScansAvailableFcnCount, "OutputFormat", "Matrix");
            % Get voltages to calculate t,p,z
            app.AnalogIn1 = app.data(:,1);
            app.AnalogIn2 = app.data(:,2);
            app.AnalogIn3 = app.data(:,3);
            T = calctemp(app,Vtemp);
            P = calcpres(app,Vpres,Vreg);
            Z = calcalt(app,T,R,g,M,Pref);

            plot(app.UIAxes_Temperature, app.timestamps, T, "b");
            plot(app.UIAxes_Pressure, app.timestamps, P, "b");
            plot(app.UIAxes_VoltageRegulator, app.timestamps, Z, "b");

            %plot(app.UIAxes_Altitude, app.timestamps, %app.AnalogIn1, "b");
            hold(app.UIAxes_Temperature, 'on');
            hold(app.UIAxes_Pressure, 'on');
            hold(app.UIAxes_VoltageRegulator, 'on');
            hold(app.UIAxes_Altitude, 'on');
        end
        
        function T = calctemp(~,Vtemp)    % Create function that calculates temperature
            T = Vtemp/0.01; 
        end
        
        function P = calcpres(~,Vpres, Vreg)  % Create function that calculates pressure
            P = (1/0.009) * ((Vpres./Vreg) + 0.095); 
        end
        
        function Alt = calcalt(~,T, R, P, g, M, Pref) % Create function that calculates altitude
            M = 0.02897;  % Molar mass of dry air kg/mol
            g = 9.80;     % acceleration due to gravity
            Pref = 101.3; % Equilibrium air pressure at sea level
            Alt = ((R*(T + 273.15))/(M*g)).*log(Pref./P);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartUpFcn(app)
            app.StopButton.Enable = "off";
            devices    = daqlist("ni");
            app.dev    = devices(1,"DeviceID");
            app.d      = daq('ni');
            %app.AnalogIn1 = addinput(app.d,app.dev.DeviceID,"ai0","Voltage"); % Temp voltage
            %app.AnalogIn2 = addinput(app.d,app.dev.DeviceID,"ai1","Voltage"); % Pres voltage
            %app.AnalogIn3 = addinput(app.d,app.dev.DeviceID,"ai2","Voltage"); % Vreg voltage
            %app.AnalogIn1.TerminalConfig = 'Differential';
            %app.AnalogIn2.TerminalConfig = 'Differential';
            %app.AnalogIn3.TerminalConfig = 'Differential';

            %app.d.Rate = 10; % Might change to 10 
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.StartButton.Enable = "off"; % Prevent double clicks
            app.StopButton.Enable = "on";
            app.SampleRate.Enable = "off";  % Prevent chanign ai channels
            app.VregChannel.Enable = "off";
            app.PressureChannel.Enable = "off";
            app.TemperatureChannel.Enable = "off";

            app.AnalogIn1 = addinput(app.d,app.dev.DeviceID,"ai0","Voltage"); % Temp voltage
            app.AnalogIn2 = addinput(app.d,app.dev.DeviceID,"ai1","Voltage"); % Pres voltage
            app.AnalogIn3 = addinput(app.d,app.dev.DeviceID,"ai2","Voltage"); % Vreg voltage
            app.AnalogIn1.TerminalConfig = 'Differential';
            app.AnalogIn2.TerminalConfig = 'Differential';
            app.AnalogIn3.TerminalConfig = 'Differential';

            %app.d.Rate = 10; % Might change to 10 

            % Create a timetable that can be appended to
            app.AllDataTimeTable = timetable(NaT(0,1),[],[],[],[],[],[]);
            app.AllDataTimeTable.Properties.VariableNames = {'V_temp','V_pressure','V_reg','T','P','Z'};
            app.AllDataTimeTable.Properties.VariableUnits = {'V','V','V','degC','kpa','m'};
           
            % sample rate
            app.d.Rate = app.SampleRate.Value;

            app.d.ScansAvailableFcnCount = app.d.Rate*1;
            app.d.ScansAvailableFcn = @(scan, evt) plotData(app, scan, evt);
            start(app.d, "Continuous");
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.StartButton.Enable = "on";
            app.StartButton.Enable = "off"; % Prevent double clicks
            app.VregChannel.Enable = "on";
            app.PressureChannel.Enable = "on";
            app.TemperatureChannel.Enable = "on";
            app.SampleRate.Enable = "on";

            % Save data
            %writetimetable(app.AllDataTimeTable,'Lab8_Barber.xls')

            stop(app.d);
            removechannel(app.d,[1:length(app.d.Channels)]);
            %StartUpFcn(app.d);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_Temperature = uiaxes(app.UIFigure);
            title(app.UIAxes_Temperature, 'Temperature')
            xlabel(app.UIAxes_Temperature, 'Time (s)')
            ylabel(app.UIAxes_Temperature, 'Temperature (°C)')
            zlabel(app.UIAxes_Temperature, 'Z')
            app.UIAxes_Pressure = uiaxes(app.UIFigure);
            title(app.UIAxes_Pressure, 'Pressure')
            xlabel(app.UIAxes_Pressure, 'Time (s)')
            ylabel(app.UIAxes_Pressure, 'Pressure (kPa)')
            zlabel(app.UIAxes_Pressure, 'Z')
            app.UIAxes_Altitude = uiaxes(app.UIFigure);
            title(app.UIAxes_Altitude, 'Altitude')
            xlabel(app.UIAxes_Altitude, 'Time (s)')
            ylabel(app.UIAxes_Altitude, 'Altitude (m)')
            zlabel(app.UIAxes_Altitude, 'Z')
            app.UIAxes_VoltageRegulator = uiaxes(app.UIFigure);
            title(app.UIAxes_VoltageRegulator, 'Voltage Regulator')
            xlabel(app.UIAxes_VoltageRegulator, 'Time (s)')
            ylabel(app.UIAxes_VoltageRegulator, 'Voltage (V)')
            zlabel(app.UIAxes_VoltageRegulator, 'Z')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.TemperatureChannelDropDownLabel = uilabel(app.UIFigure);
            app.TemperatureChannelDropDownLabel.Text = 'Temperature Channel';
            app.TemperatureChannel = uidropdown(app.UIFigure);
            app.TemperatureChannel.Items = {'Channel 0', 'Channel 1', 'Channel 2', 'Channel 3', 'Channel 4', 'Channel 5', 'Channel 6', 'Channel 7'};
            app.TemperatureChannel.ItemsData = {'ai0', 'ai1', 'ai2', 'ai3', 'ai4', 'ai5', 'ai6', 'ai7'};
            app.TemperatureChannel.Value = 'ai0';
            app.PressureChannelDropDownLabel = uilabel(app.UIFigure);
            app.PressureChannelDropDownLabel.Text = 'Pressure Channel';
            app.PressureChannel = uidropdown(app.UIFigure);
            app.PressureChannel.Items = {'Channel 0', 'Channel 1', 'Channel 2', 'Channel 3', 'Channel 4', 'Channel 5', 'Channel 6', 'Channel 7'};
            app.PressureChannel.ItemsData = {'ai0', 'ai1', 'ai2', 'ai3', 'ai4', 'ai5', 'ai6', 'ai7'};
            app.PressureChannel.Value = 'ai1';
            app.VregChannelDropDownLabel = uilabel(app.UIFigure);
            app.VregChannelDropDownLabel.Text = 'Vreg Channel';
            app.VregChannel = uidropdown(app.UIFigure);
            app.VregChannel.Items = {'Channel 0', 'Channel 1', 'Channel 2', 'Channel 3', 'Channel 4', 'Channel 5', 'Channel 6', 'Channel 7'};
            app.VregChannel.ItemsData = {'ai0', 'ai1', 'ai2', 'ai3', 'ai4', 'ai5', 'ai6', 'ai7', 'ai8'};
            app.VregChannel.Value = 'ai2';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.Text = 'Clear';
            app.SampleRateHzEditFieldLabel = uilabel(app.UIFigure);
            app.SampleRateHzEditFieldLabel.Text = 'Sample Rate (Hz)';
            app.SampleRate = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @StartUpFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end