classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SampleRate                    matlab.ui.control.NumericEditField
        SampleRateHzEditFieldLabel    matlab.ui.control.Label
        ClearButton                   matlab.ui.control.Button
        VregChannelDropDown           matlab.ui.control.DropDown
        VregChannelDropDownLabel      matlab.ui.control.Label
        PressureChannelDropDown       matlab.ui.control.DropDown
        PressureChannelDropDownLabel  matlab.ui.control.Label
        TemperatureChannelDropDown    matlab.ui.control.DropDown
        TemperatureChannelDropDownLabel  matlab.ui.control.Label
        StopButton                    matlab.ui.control.Button
        StartButton                   matlab.ui.control.Button
        UIAxes_VoltageRegulator       matlab.ui.control.UIAxes
        UIAxes_Altitude               matlab.ui.control.UIAxes
        UIAxes_Pressure               matlab.ui.control.UIAxes
        UIAxes_Temperature            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        AnalogIn0  % Analog input (Temp sensor)
        AnalogIn1  % Analog input (Pres sensor)
        AnalogIn2  % Analog input (Voltreg)
        d          % Daq session
        devID        % Daq device
        inTemp_v
        inPres_v
        reg_v
        chan_t     % Variable for temperature channel
        chan_p     % Variable for pressure channel
        chan_v     % Variable for voltage regulator channel
        data       % Matrix of voltage channels
        VariableNames = {'Temperature Voltage','Pressure Voltage','Regulator Voltage','Temperature','Pressure','Altitude'};
        VariableUnits = {'V','V','V','C','kPa','m'};
        timestamps % Read in with data as a matrix
        AllDataTimeTable
        NewDataTimeTable
        M = 0.02897;  % Molar mass of dry air kg/mol
        g = 9.80;     % acceleration due to gravity (m/s^2)
        Pref = 101.3; % Equilibrium air pressure at sea level (kpa)
        R = 8.314;    % Universal gas constant 


    end
    
    methods (Access = private)
        
        function plotData(app, scan, ~)
            [app.data, app.timestamps, ~] = read(scan, scan.ScansAvailableFcnCount, "OutputFormat", "Matrix");
            % Get voltages to calculate t,p,z
            app.inTemp_v= app.data(:,1);
            app.inPres_v= app.data(:,2);
            app.reg_v= app.data(:,3);

            
            T = calctemp(app,app.inTemp_v);
            P = calcpres(app,app.inPres_v,app.reg_v);
            Z = ((app.R*(T + 273.15))/(app.M*app.g)).*log(app.Pref./P);
            plot(app.UIAxes_Temperature, app.timestamps, T, "b");
            plot(app.UIAxes_Pressure, app.timestamps, P, "b");
            plot(app.UIAxes_VoltageRegulator, app.timestamps, app.reg_v, "b");
            plot(app.UIAxes_Altitude, app.timestamps, Z, "b");
            hold(app.UIAxes_Temperature, 'on');
            hold(app.UIAxes_Pressure, 'on');
            hold(app.UIAxes_VoltageRegulator, 'on');
            hold(app.UIAxes_Altitude, 'on');

            app.NewDataTimeTable = timetable(datetime('now'),mean(app.inTemp_v),mean(app.inPres_v),mean(app.reg_v), ...
                                                            mean(T),mean(P),mean(Z));
            app.NewDataTimeTable.Properties.VariableNames=app.AllDataTimeTable.Properties.VariableNames;
            app.AllDataTimeTable=vertcat(app.AllDataTimeTable,app.NewDataTimeTable); 

        end
        
        function T = calctemp(~,Vtemp)    % Create function that calculates temperature
            T = Vtemp/0.01; 
        end
        
        function P = calcpres(~,Vpres, Vreg)  % Create function that calculates pressure
            P = (1/0.009) * ((Vpres./Vreg) + 0.095); 
        end
        
        function Z = calcalt(~,T, P) % Create function that calculates altitude
            
            Z = ((app.R*(T + 273.15))/(app.M*app.g)).*log(app.Pref./P);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartUpFcn(app)
            app.StopButton.Enable = "off";
            devices    = daqlist("ni");
            app.devID    = devices.DeviceID(1);
            app.d      = daq('ni');
            app.chan_t= app.TemperatureChannelDropDown.Value;
            app.chan_p = app.PressureChannelDropDown.Value;
            app.chan_v = app.VregChannelDropDown.Value;
            app.d.Rate = 10; % Might change to 10 
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.StartButton.Enable = "off"; % Prevent double clicks
            app.StopButton.Enable = "on";
            app.SampleRate.Enable = "off";  % Prevent chanign ai channels
            app.TemperatureChannelDropDown.Enable = "off";
            app.PressureChannelDropDown.Enable = "off";
            app.VregChannelDropDown.Enable = "off";
            
            app.AnalogIn0 = addinput(app.d,app.devID,app.chan_t,"Voltage"); % Temp voltage
            app.AnalogIn1 = addinput(app.d,app.devID,app.chan_p,"Voltage"); % Pres voltage
            app.AnalogIn2 = addinput(app.d,app.devID,app.chan_v,"Voltage"); % Vreg voltage
            app.AnalogIn0.TerminalConfig = 'Differential';
            app.AnalogIn1.TerminalConfig = 'Differential';
            app.AnalogIn2.TerminalConfig = 'Differential';


            % Create a timetable that can be appended to
            app.AllDataTimeTable = timetable(NaT(0,1),[],[],[],[],[],[]);
            app.AllDataTimeTable.Properties.VariableNames = {'V_temp','V_pressure','V_reg','T','P','Z'};
            app.AllDataTimeTable.Properties.VariableUnits = {'V','V','V','degC','kpa','m'};
            
            
            %Clear plots for consecutive measurements
            cla(app.UIAxes_Temperature);    
            cla(app.UIAxes_Pressure);
            cla(app.UIAxes_VoltageRegulator);
            cla(app.UIAxes_Altitude);

            app.d.ScansAvailableFcnCount = app.d.Rate*1;  % sample rate
            app.d.ScansAvailableFcn = @(scan, evt) plotData(app, scan);
            start(app.d, "Continuous");
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.StopButton.Enable = "off";
            app.StartButton.Enable = "on";
            %app.StartButton.Enable = "off"; % Prevent double clicks
            app.VregChannelDropDown.Enable = "on";
            app.PressureChannelDropDown.Enable = "on";
            app.TemperatureChannelDropDown.Enable = "on";
            app.SampleRate.Enable = "on";

            writetimetable(app.AllDataTimeTable,"Barber_Lab08data.csv");

            stop(app.d);
            removechannel(app.d,1:length(app.d.Channels));
        end

        % Value changed function: VregChannelDropDown
        function VregChannelDropDownValueChanged(app, event)
            app.chan_v= app.VregChannelDropDown.Value;      
        end

        % Value changed function: TemperatureChannelDropDown
        function TemperatureChannelDropDownValueChanged(app, event)
            app.chan_t= app.TemperatureChannelDropDown.Value;
        end

        % Value changed function: PressureChannelDropDown
        function PressureChannelDropDownValueChanged(app, event)
            app.chan_p = app.PressureChannelDropDown.Value;
        end

        % Value changed function: SampleRate
        function SampleRateValueChanged(app, event)
            app.d.Rate = app.SampleRate.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_Temperature = uiaxes(app.UIFigure);
            title(app.UIAxes_Temperature, 'Temperature')
            xlabel(app.UIAxes_Temperature, 'Time (s)')
            ylabel(app.UIAxes_Temperature, 'Temperature (°C)')
            zlabel(app.UIAxes_Temperature, 'Z')
            app.UIAxes_Pressure = uiaxes(app.UIFigure);
            title(app.UIAxes_Pressure, 'Pressure')
            xlabel(app.UIAxes_Pressure, 'Time (s)')
            ylabel(app.UIAxes_Pressure, 'Pressure (kPa)')
            zlabel(app.UIAxes_Pressure, 'Z')
            app.UIAxes_Altitude = uiaxes(app.UIFigure);
            title(app.UIAxes_Altitude, 'Altitude')
            xlabel(app.UIAxes_Altitude, 'Time (s)')
            ylabel(app.UIAxes_Altitude, 'Altitude (m)')
            zlabel(app.UIAxes_Altitude, 'Z')
            app.UIAxes_VoltageRegulator = uiaxes(app.UIFigure);
            title(app.UIAxes_VoltageRegulator, 'Voltage Regulator')
            xlabel(app.UIAxes_VoltageRegulator, 'Time (s)')
            ylabel(app.UIAxes_VoltageRegulator, 'Voltage (V)')
            zlabel(app.UIAxes_VoltageRegulator, 'Z')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.TemperatureChannelDropDownLabel = uilabel(app.UIFigure);
            app.TemperatureChannelDropDownLabel.Text = 'Temperature Channel';
            app.TemperatureChannelDropDown = uidropdown(app.UIFigure);
            app.TemperatureChannelDropDown.Items = {'ai0', 'ai1', 'ai2', 'ai3', 'ai4', 'ai5', 'ai6', 'ai7'};
            app.TemperatureChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @TemperatureChannelDropDownValueChanged, true);
            app.TemperatureChannelDropDown.Value = 'ai0';
            app.PressureChannelDropDownLabel = uilabel(app.UIFigure);
            app.PressureChannelDropDownLabel.Text = 'Pressure Channel';
            app.PressureChannelDropDown = uidropdown(app.UIFigure);
            app.PressureChannelDropDown.Items = {'ai0', 'ai1', 'ai2', 'ai3', 'ai4', 'ai5', 'ai6', 'ai7'};
            app.PressureChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @PressureChannelDropDownValueChanged, true);
            app.PressureChannelDropDown.Value = 'ai1';
            app.VregChannelDropDownLabel = uilabel(app.UIFigure);
            app.VregChannelDropDownLabel.Text = 'Vreg Channel';
            app.VregChannelDropDown = uidropdown(app.UIFigure);
            app.VregChannelDropDown.Items = {'ai0', 'ai1', 'ai2', 'ai3', 'ai4', 'ai5', 'ai6', 'ai7'};
            app.VregChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @VregChannelDropDownValueChanged, true);
            app.VregChannelDropDown.Value = 'ai2';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.Text = 'Clear';
            app.SampleRateHzEditFieldLabel = uilabel(app.UIFigure);
            app.SampleRateHzEditFieldLabel.Text = 'Sample Rate (Hz)';
            app.SampleRate = uieditfield(app.UIFigure, 'numeric');
            app.SampleRate.ValueChangedFcn = createCallbackFcn(app, @SampleRateValueChanged, true);
            app.SampleRate.Value = 10;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @StartUpFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end